classdef GuiZiRingdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiRingdownUIFigure             matlab.ui.Figure
        EnabletriggeringButton         matlab.ui.control.StateButton
        RecordingLampLabel             matlab.ui.control.Label
        RecordingLamp                  matlab.ui.control.Lamp
        OscillatorinuseEditFieldLabel  matlab.ui.control.Label
        OscillatorinuseEditField       matlab.ui.control.NumericEditField
        RecordtimesEditFieldLabel      matlab.ui.control.Label
        RecordtimesEditField           matlab.ui.control.NumericEditField
        TimeelapsedsEditFieldLabel     matlab.ui.control.Label
        TimeelapsedsEditField          matlab.ui.control.NumericEditField
        AdaptivedemodCheckBox          matlab.ui.control.CheckBox
        TriggerthresholdVEditFieldLabel  matlab.ui.control.Label
        TriggerthresholdVEditField     matlab.ui.control.NumericEditField
        TraceUIAxes                    matlab.ui.control.UIAxes
        NaverageEditFieldLabel         matlab.ui.control.Label
        NaverageEditField              matlab.ui.control.NumericEditField
        DriveLampLabel                 matlab.ui.control.Label
        DriveLamp                      matlab.ui.control.Lamp
        AutosaveeachtraceCheckBox      matlab.ui.control.CheckBox
        CompletedaveragesEditFieldLabel  matlab.ui.control.Label
        CompletedaveragesEditField     matlab.ui.control.NumericEditField
        DownsamplingfactorEditFieldLabel  matlab.ui.control.Label
        DownsamplingfactorEditField    matlab.ui.control.NumericEditField
        DemodUIAxes                    matlab.ui.control.UIAxes
        FFTlengthptEditFieldLabel      matlab.ui.control.Label
        FFTlengthptEditField           matlab.ui.control.NumericEditField
        FFTRBWHzEditFieldLabel         matlab.ui.control.Label
        FFTRBWHzEditField              matlab.ui.control.NumericEditField
        DownsampledratesamplessEditFieldLabel  matlab.ui.control.Label
        DownsampledrateEditField       matlab.ui.control.NumericEditField
        ylimitmodeLabel                matlab.ui.control.Label
        YLimModeDropDown               matlab.ui.control.DropDown
        yminEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        flimitmodeLabel                matlab.ui.control.Label
        XLimModeDropDown               matlab.ui.control.DropDown
        fmaxEditFieldLabel             matlab.ui.control.Label
        fmaxEditField                  matlab.ui.control.NumericEditField
        OscfollowingLampLabel          matlab.ui.control.Label
        OscfollowingLamp               matlab.ui.control.Lamp
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = ''  % App name to clear on exit
        Listeners = struct()
    end

    methods (Access = public)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
    
    end
    
    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            linkGuiElement(app, app.RecordingLamp, '.Instr.recording');
            linkGuiElement(app, app.DriveLamp, '.Instr.drive_on');
            linkGuiElement(app, app.EnabletriggeringButton, '.Instr.enable_trig');
            linkGuiElement(app, app.TriggerthresholdVEditField, '.Instr.trig_threshold');
            linkGuiElement(app, app.RecordtimesEditField, '.Instr.record_time');
            linkGuiElement(app, app.OscillatorinuseEditField, '.Instr.current_osc');
            linkGuiElement(app, app.TimeelapsedsEditField, '.Instr.elapsed_t');
            
            linkGuiElement(app, app.NaverageEditField, '.Instr.n_avg');
            linkGuiElement(app, app.CompletedaveragesEditField, '.Instr.n_avg_completed');
            
            linkGuiElement(app, app.FFTlengthptEditField, '.Instr.fft_length');
            linkGuiElement(app, app.FFTRBWHzEditField, '.Instr.fft_rbw');
            linkGuiElement(app, app.DownsamplingfactorEditField, '.Instr.downsample_n');
            linkGuiElement(app, app.DownsampledrateEditField, '.Instr.downsampled_rate');
            
            linkGuiElement(app, app.AdaptivedemodCheckBox, '.Instr.adaptive_meas_osc');
            linkGuiElement(app, app.OscfollowingLamp, '.Instr.ad_osc_following');
            
            % Demodulator axes limits
            linkGuiElement(app, app.yminEditField, '.DemodUIAxes.YLim(1)');
            linkGuiElement(app, app.ymaxEditField, '.DemodUIAxes.YLim(2)');
            linkGuiElement(app, app.YLimModeDropDown, '.DemodUIAxes.YLimMode');
            % X limits are symmetric
            linkGuiElement(app, app.fmaxEditField, '.DemodUIAxes.XLim(2)');
            linkGuiElement(app, app.XLimModeDropDown, '.DemodUIAxes.XLimMode');
        end
    
        function updatePlot(app)
            % Plot the trace using its class method
            plot(app.Instr.Trace, app.TraceUIAxes);
            plot(app.Instr.DemodSpectrum, app.DemodUIAxes);
            
            % Update the plot-related controls
            updateLinkedElement(app, app.yminEditField);
            updateLinkedElement(app, app.ymaxEditField);
            updateLinkedElement(app, app.fmaxEditField);
            
            % Elapsed time indicator
            updateLinkedElement(app, app.TimeelapsedsEditField);
        end
        
        function updateGui(app)
            updateLinkedGuiElements(app)
            updatePlot(app)
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            createLinks(app);
            
            app.Listeners.NewDemodSample=addlistener(app.Instr,'NewDemodSample',@(~,~)updatePlot(app));
            app.Listeners.NewData=addlistener(app.Instr,'NewData',@(~,~)updateGui(app));
            app.Listeners.NewSetting=addlistener(app.Instr,'NewSetting',@(~,~)updateGui(app));
            
            updateGui(app);
            
            % Start continuous data acquisition
            startPoll(app.Instr);
        end

        % Close request function: ZiRingdownUIFigure
        function ZiRingdownUIFigureCloseRequest(app, event)
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end

        % Value changed function: EnabletriggeringButton
        function EnabletriggeringButtonValueChanged(app, event)
            value = app.EnabletriggeringButton.Value;
            if value && ~app.Instr.recording
                app.Instr.drive_on=true;
                resetAveraging(app.Instr); % Set the averaging counter to 0
            else
                app.Instr.drive_on=false;
            end
            app.Instr.enable_trig=value;
            updateGui(app);
        end

        % Value changed function: fmaxEditField
        function fmaxEditFieldValueChanged(app, event)
            value = app.fmaxEditField.Value;
            app.DemodUIAxes.XLim(1)=-abs(value);
            app.DemodUIAxes.XLim(2)=abs(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiRingdownUIFigure = uifigure;
            app.ZiRingdownUIFigure.Name = 'Template';
            app.ZiRingdownUIFigure.CloseRequestFcn = createCallbackFcn(app, @ZiRingdownUIFigureCloseRequest, true);
            app.EnabletriggeringButton = uibutton(app.ZiRingdownUIFigure, 'state');
            app.EnabletriggeringButton.ValueChangedFcn = createCallbackFcn(app, @EnabletriggeringButtonValueChanged, true);
            app.EnabletriggeringButton.Text = 'Enable triggering';
            app.RecordingLampLabel = uilabel(app.ZiRingdownUIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.ZiRingdownUIFigure);
            app.OscillatorinuseEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.OscillatorinuseEditFieldLabel.Text = 'Oscillator in use';
            app.OscillatorinuseEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.OscillatorinuseEditField.ValueDisplayFormat = '%.0f';
            app.OscillatorinuseEditField.Editable = 'off';
            app.RecordtimesEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.RecordtimesEditFieldLabel.Text = 'Record time (s)';
            app.RecordtimesEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.RecordtimesEditField.Value = 150;
            app.TimeelapsedsEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.TimeelapsedsEditFieldLabel.Text = 'Time elapsed (s)';
            app.TimeelapsedsEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.TimeelapsedsEditField.ValueDisplayFormat = '%.1f';
            app.TimeelapsedsEditField.Editable = 'off';
            app.AdaptivedemodCheckBox = uicheckbox(app.ZiRingdownUIFigure);
            app.AdaptivedemodCheckBox.Text = 'Adaptive demod frequency';
            app.TriggerthresholdVEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.TriggerthresholdVEditFieldLabel.Text = 'Trigger threshold (V)';
            app.TriggerthresholdVEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.TraceUIAxes = uiaxes(app.ZiRingdownUIFigure);
            xlabel(app.TraceUIAxes, 'Time')
            ylabel(app.TraceUIAxes, 'r')
            app.NaverageEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.NaverageEditFieldLabel.Text = 'N average';
            app.NaverageEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.NaverageEditField.Value = 1;
            app.DriveLampLabel = uilabel(app.ZiRingdownUIFigure);
            app.DriveLampLabel.Text = 'Drive';
            app.DriveLamp = uilamp(app.ZiRingdownUIFigure);
            app.AutosaveeachtraceCheckBox = uicheckbox(app.ZiRingdownUIFigure);
            app.AutosaveeachtraceCheckBox.Enable = 'off';
            app.AutosaveeachtraceCheckBox.Text = 'Auto save each trace';
            app.CompletedaveragesEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.CompletedaveragesEditFieldLabel.Text = 'Completed averages';
            app.CompletedaveragesEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.CompletedaveragesEditField.ValueDisplayFormat = '%.0f';
            app.DownsamplingfactorEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.DownsamplingfactorEditFieldLabel.Text = 'Downsampling factor';
            app.DownsamplingfactorEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.DownsamplingfactorEditField.ValueDisplayFormat = '%.0f';
            app.DemodUIAxes = uiaxes(app.ZiRingdownUIFigure);
            title(app.DemodUIAxes, 'Demodulator spectrum')
            xlabel(app.DemodUIAxes, 'Frequency (Hz)')
            ylabel(app.DemodUIAxes, 'PSD')
            app.DemodUIAxes.YScale = 'log';
            app.FFTlengthptEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.FFTlengthptEditFieldLabel.Text = 'FFT length (pt)';
            app.FFTlengthptEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.FFTlengthptEditField.Value = 16;
            app.FFTRBWHzEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.FFTRBWHzEditFieldLabel.Text = 'FFT RBW (Hz)';
            app.FFTRBWHzEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.FFTRBWHzEditField.Value = 10;
            app.DownsampledratesamplessEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.DownsampledratesamplessEditFieldLabel.Text = 'Downsampled rate (samples/s)';
            app.DownsampledrateEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.DownsampledrateEditField.ValueDisplayFormat = '%.0f';
            app.ylimitmodeLabel = uilabel(app.ZiRingdownUIFigure);
            app.ylimitmodeLabel.Text = 'y limit mode';
            app.YLimModeDropDown = uidropdown(app.ZiRingdownUIFigure);
            app.YLimModeDropDown.Items = {'auto', 'manual'};
            app.YLimModeDropDown.Value = 'manual';
            app.yminEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.flimitmodeLabel = uilabel(app.ZiRingdownUIFigure);
            app.flimitmodeLabel.Text = 'f limit mode';
            app.XLimModeDropDown = uidropdown(app.ZiRingdownUIFigure);
            app.XLimModeDropDown.Items = {'auto', 'manual'};
            app.XLimModeDropDown.Value = 'manual';
            app.fmaxEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.fmaxEditFieldLabel.Text = 'f max';
            app.fmaxEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.fmaxEditField.ValueChangedFcn = createCallbackFcn(app, @fmaxEditFieldValueChanged, true);
            app.OscfollowingLampLabel = uilabel(app.ZiRingdownUIFigure);
            app.OscfollowingLampLabel.Text = 'Osc following';
            app.OscfollowingLamp = uilamp(app.ZiRingdownUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiZiRingdown(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiRingdownUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiRingdownUIFigure)
        end
    end
end