classdef DataAcquisitionMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataacquisitionmenuUIFigure  matlab.ui.Figure
        ProgramTable                 matlab.ui.control.Table
        LocalbasedirectoryEditFieldLabel  matlab.ui.control.Label
        LocalbasedirectoryEditField  matlab.ui.control.EditField
        RunButton                    matlab.ui.control.Button
        InstrumentmanagerButton      matlab.ui.control.Button
        DeleterunfileButton          matlab.ui.control.Button
        ReloadlistButton             matlab.ui.control.Button
        RunfileheaderTextAreaLabel   matlab.ui.control.Label
        RunfileheaderTextArea        matlab.ui.control.TextArea
    end


    properties (Access = private)
        run_files %
        row_sel % selected row
    end

    methods (Access = private)
    
        function initProgramTable(app)
            app.run_files = readRunFiles();
            app.ProgramTable.Data = menuTitlesFromRunFiles(app.run_files);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dir = getLocalBaseDir();
            app.LocalbasedirectoryEditField.Value = dir;
            initProgramTable(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataacquisitionmenuUIFigure = uifigure;
            app.DataacquisitionmenuUIFigure.Name = 'Data acquisition menu';
            app.ProgramTable = uitable(app.DataacquisitionmenuUIFigure);
            app.ProgramTable.ColumnName = {'Program'};
            app.ProgramTable.RowName = {};
            app.LocalbasedirectoryEditFieldLabel = uilabel(app.DataacquisitionmenuUIFigure);
            app.LocalbasedirectoryEditFieldLabel.Text = 'Local base directory';
            app.LocalbasedirectoryEditField = uieditfield(app.DataacquisitionmenuUIFigure, 'text');
            app.RunButton = uibutton(app.DataacquisitionmenuUIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.InstrumentmanagerButton = uibutton(app.DataacquisitionmenuUIFigure, 'push');
            app.InstrumentmanagerButton.Text = 'Instrument manager';
            app.DeleterunfileButton = uibutton(app.DataacquisitionmenuUIFigure, 'push');
            app.DeleterunfileButton.Text = 'Delete run file';
            app.ReloadlistButton = uibutton(app.DataacquisitionmenuUIFigure, 'push');
            app.ReloadlistButton.Text = 'Reload list';
            app.RunfileheaderTextAreaLabel = uilabel(app.DataacquisitionmenuUIFigure);
            app.RunfileheaderTextAreaLabel.Text = 'Run file header';
            app.RunfileheaderTextArea = uitextarea(app.DataacquisitionmenuUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataAcquisitionMenu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataacquisitionmenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataacquisitionmenuUIFigure)
        end
    end
end