classdef InstrumentManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManagethelistofinstrumentsUIFigure  matlab.ui.Figure
        AddinstrumentButton          matlab.ui.control.Button
        DeleteinstrumentButton       matlab.ui.control.Button
        SavechangesButton            matlab.ui.control.Button
        ReloadlistButton             matlab.ui.control.Button
        ContinueButton               matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        InstrumentListlocationLabel  matlab.ui.control.Label
        BaseDirEditField             matlab.ui.control.EditField
        InstrumentsListBoxLabel      matlab.ui.control.Label
        InstrListBox                 matlab.ui.control.ListBox
        PropertiesPanel              matlab.ui.container.Panel
        ShowinDAQCheckBox            matlab.ui.control.CheckBox
        InterfaceEditFieldLabel      matlab.ui.control.Label
        InterfaceEditField           matlab.ui.control.EditField
        AddressEditFieldLabel        matlab.ui.control.Label
        AddressEditField             matlab.ui.control.EditField
        ControlclassEditFieldLabel   matlab.ui.control.Label
        ControlclassEditField        matlab.ui.control.EditField
        GUIEditFieldLabel            matlab.ui.control.Label
        GUIEditField                 matlab.ui.control.EditField
        MenutitleEditFieldLabel      matlab.ui.control.Label
        MenutitleEditField           matlab.ui.control.EditField
    end


    properties (Access = public)   
        TmpInstrList;
        Ret % variable with the value passed by reference to return the choosen device
        
        NORMAL_COLOR = [0.47, 0.67, 0.19];
        ERROR_COLOR = [1, 0, 0];
        WARNING_COLOR = [0.93, 0.69, 0.13];
        
        EDIT_BG_COLOR = [1,1,0.9]; % yellow if changes unsaved
        STD_BG_COLOR = [1,1,1]; % white
    end

    methods (Access = private)
    
        function updatePropertiesPanel(app)
            if ~isempty(app.InstrListBox.Value)
                name = app.InstrListBox.Value;
                app.InterfaceEditField.Value = app.TmpInstrList.(name).interface;
                app.AddressEditField.Value = app.TmpInstrList.(name).address;
                try
                    app.ControlclassEditField.Value = app.TmpInstrList.(name).control_class;
                catch
                end
                try
                    app.GUIEditField.Value = app.TmpInstrList.(name).gui;
                catch
                end
                try
                    app.GUIEditField.Value = app.TmpInstrList.(name).gui;
                catch
                end
                try
                    app.MenutitleEditField.Value = app.TmpInstrList.(name).menu_title;
                catch
                end
            else
                app.InterfaceEditField.Value = '';
                app.AddressEditField.Value = '';
                app.ControlclassEditField.Value = '';
                app.GUIEditField.Value = '';
                app.MenutitleEditField.Value = '';
            end
        end
    
        function updateTmpInstrList(app)           
            name = app.InstrListBox.Value;
            app.TmpInstrList.(name).interface = app.InterfaceEditField.Value;
            app.TmpInstrList.(name).address = app.AddressEditField.Value;
            app.TmpInstrList.(name).control_class = app.ControlclassEditField.Value;
            app.TmpInstrList.(name).gui = app.GUIEditField.Value;
            app.TmpInstrList.(name).menu_title = app.MenutitleEditField.Value;
            setStatus(app, 'edits_unsaved');
        end    
        
        function setStatus(app, status)
            switch status
                case 'edits_unsaved'
                    app.InstrListBox.BackgroundColor = app.EDIT_BG_COLOR;
                    app.StatusLabel.Text = 'List of instruments contains unsaved changes';
                    app.StatusLabel.FontColor = app.WARNING_COLOR;
                case 'edits_saved'
                    app.InstrListBox.BackgroundColor = app.STD_BG_COLOR;
                    app.StatusLabel.Text = 'Changes saved';
                    app.StatusLabel.FontColor = app.NORMAL_COLOR;
                otherwise
                    error('Unknown table status')
            end
        end       

        function saveInstrumentList(app)
            InstrumentList = app.TmpInstrList;
            dir = getLocalBaseDir();
            file_name = fullfile(dir,'LocalInstrumentControlSettings.mat');
            save(file_name, 'InstrumentList','-append');
            setStatus(app, 'edits_saved');
        end
    
        function loadInstrumentList(app)
            app.TmpInstrList = getLocalSettings('InstrumentList');           
            instr_names = fieldnames(app.TmpInstrList);
            app.InstrListBox.Items = instr_names;          
            updatePropertiesPanel(app);
            setStatus(app, 'edits_saved');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % The line below will throw an error if the settings file does not exist
            app.BaseDirEditField.Value = getLocalBaseDir();
            % Display instrument list
            loadInstrumentList(app);
            
            % The variable, to which the output will be assigned
            app.ContinueButton.Enable = 'off';
            if isempty(varargin)
                app.Ret = MyRefVar('');
            else
                if isequal(class(varargin{1}),'MyRefVar')
                    app.Ret = varargin{1};
                    % Enable the continue button only if appropriate MyRefVar
                    % was supplied to the input
                    app.ContinueButton.Enable = 'on';
                else
                    app.ErrorwarningTextArea.Value = ...
                        ['The input variable class needs to be MyRefVar.',... 
                        ' The selected value will not be returned'];
                    app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
                    app.Ret = MyRefVar('');
                end
            end
        end

        % Button pushed function: AddinstrumentButton
        function AddinstrumentButtonPushed(app, event)
            newaddr = MyRefVar('');
            waitfor(FindInstrumentDlg(newaddr));
            if ~isequal(newaddr.value,'')
                tmp_name = genvarname('instrument', fieldnames(app.TmpInstrList));
                app.TmpInstrList.(tmp_name) = struct();
                app.TmpInstrList.(tmp_name).name = tmp_name;
                app.TmpInstrList.(tmp_name).interface = 'constructor';
                app.TmpInstrList.(tmp_name).address = newaddr.value;
                
                instr_names = fieldnames(app.TmpInstrList);
                app.InstrListBox.Items = instr_names; 
                setStatus(app, 'edits_unsaved');
            end
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            saveInstrumentList(app);
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            loadInstrumentList(app);
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            % Find the selected table row
            name = app.InstrListBox.Value;
            app.TmpInstrList = rmfield(app.TmpInstrList, name);
            instr_names = fieldnames(app.TmpInstrList);
            app.InstrListBox.Items = instr_names;
            updatePropertiesPanel(app);
            setStatus(app, 'edits_unsaved');
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Save changes
            saveInstrumentList(app);
            % Find the selected table row
            name = getSelectedInstrumentName(app);
            app.Ret.value = InstrumentList.(name);
            delete(app)
        end

        % Value changed function: InstrListBox
        function InstrListBoxValueChanged(app, event)
            updatePropertiesPanel(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManagethelistofinstrumentsUIFigure = uifigure;
            app.ManagethelistofinstrumentsUIFigure.Name = 'Manage the list of instruments';
            app.AddinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.AddinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @AddinstrumentButtonPushed, true);
            app.AddinstrumentButton.Text = 'Add instrument';
            app.DeleteinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.SavechangesButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.ReloadlistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.ContinueButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.StatusLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.StatusLabel.Text = 'Status';
            app.InstrumentListlocationLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentListlocationLabel.Text = 'InstrumentList location';
            app.BaseDirEditField = uieditfield(app.ManagethelistofinstrumentsUIFigure, 'text');
            app.BaseDirEditField.Editable = 'off';
            app.InstrumentsListBoxLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentsListBoxLabel.Text = 'Instruments';
            app.InstrListBox = uilistbox(app.ManagethelistofinstrumentsUIFigure);
            app.InstrListBox.ValueChangedFcn = createCallbackFcn(app, @InstrListBoxValueChanged, true);
            app.PropertiesPanel = uipanel(app.ManagethelistofinstrumentsUIFigure);
            app.PropertiesPanel.Title = 'Properties';
            app.ShowinDAQCheckBox = uicheckbox(app.PropertiesPanel);
            app.ShowinDAQCheckBox.Text = 'Show in DAQ?';
            app.ShowinDAQCheckBox.Value = true;
            app.InterfaceEditFieldLabel = uilabel(app.PropertiesPanel);
            app.InterfaceEditFieldLabel.Text = 'Interface';
            app.InterfaceEditField = uieditfield(app.PropertiesPanel, 'text');
            app.AddressEditFieldLabel = uilabel(app.PropertiesPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.PropertiesPanel, 'text');
            app.ControlclassEditFieldLabel = uilabel(app.PropertiesPanel);
            app.ControlclassEditFieldLabel.Text = 'Control class';
            app.ControlclassEditField = uieditfield(app.PropertiesPanel, 'text');
            app.GUIEditFieldLabel = uilabel(app.PropertiesPanel);
            app.GUIEditFieldLabel.Text = 'GUI';
            app.GUIEditField = uieditfield(app.PropertiesPanel, 'text');
            app.MenutitleEditFieldLabel = uilabel(app.PropertiesPanel);
            app.MenutitleEditFieldLabel.Text = 'Menu title';
            app.MenutitleEditField = uieditfield(app.PropertiesPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = InstrumentManager(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManagethelistofinstrumentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManagethelistofinstrumentsUIFigure)
        end
    end
end